classdef ejemplo_axes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Axes_example  matlab.ui.Figure
        GusanoButton  matlab.ui.control.Button
        TortaButton   matlab.ui.control.Button
        SenoButton    matlab.ui.control.Button
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SenoButton
        function SenoButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            t = 0:0.001:2*pi;
            seno = sin(2*t);
            plot(app.UIAxes,t,seno);
        end

        % Button pushed function: TortaButton
        function TortaButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            datos = [40,15,45];
            pie3(app.UIAxes,datos,[1,1,0]);
        end

        % Button pushed function: GusanoButton
        function GusanoButtonPushed(app, event)
            cla(app.UIAxes,"reset");
            t = 0:pi/50:10*pi;
            st = sin(t);
            ct = cos(t);
            plot3(app.UIAxes,st,ct,t);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Axes_example = uifigure('Visible', 'off');
            app.Axes_example.Name = 'Axes App';
            app.UIAxes = uiaxes(app.Axes_example);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            colormap(app.UIAxes, 'copper')
            app.SenoButton = uibutton(app.Axes_example, 'push');
            app.SenoButton.ButtonPushedFcn = createCallbackFcn(app, @SenoButtonPushed, true);
            app.SenoButton.Text = 'Seno';
            app.TortaButton = uibutton(app.Axes_example, 'push');
            app.TortaButton.ButtonPushedFcn = createCallbackFcn(app, @TortaButtonPushed, true);
            app.TortaButton.Text = 'Torta';
            app.GusanoButton = uibutton(app.Axes_example, 'push');
            app.GusanoButton.ButtonPushedFcn = createCallbackFcn(app, @GusanoButtonPushed, true);
            app.GusanoButton.Text = 'Gusano';
            % Show the figure after all components are created
            app.Axes_example.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ejemplo_axes

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Axes_example)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Axes_example)
        end
    end
end