classdef ejemplo_checkbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        c3        matlab.ui.control.CheckBox
        c2        matlab.ui.control.CheckBox
        c1        matlab.ui.control.CheckBox
        axes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: c1
        function c1ValueChanged(app, event)
            value = app.c1.Value;
            if value == 1
                app.axes.XGrid = 'on';
                app.axes.YGrid = 'on';
            else
                app.axes.XGrid = "off";
                app.axes.YGrid = "off";
            end
        end

        % Value changed function: c2
        function c2ValueChanged(app, event)
            value = app.c2.Value;
            if value == 1
                app.axes.Title.String = 'Gráfica';
            else
                app.axes.Title.String = '';

            end
        end

        % Value changed function: c3
        function c3ValueChanged(app, event)
            value = app.c3.Value;
            if value == 1
                app.axes.Visible = 'off';
            else
                app.axes.Visible = 'on';

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Title')
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.c1 = uicheckbox(app.UIFigure);
            app.c1.ValueChangedFcn = createCallbackFcn(app, @c1ValueChanged, true);
            app.c1.Text = 'Rejilla';
            app.c2 = uicheckbox(app.UIFigure);
            app.c2.ValueChangedFcn = createCallbackFcn(app, @c2ValueChanged, true);
            app.c2.Text = 'Títilo';
            app.c3 = uicheckbox(app.UIFigure);
            app.c3.ValueChangedFcn = createCallbackFcn(app, @c3ValueChanged, true);
            app.c3.Text = 'Inisible';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ejemplo_checkbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end