classdef image_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BlancoynegroButton  matlab.ui.control.Button
        Button              matlab.ui.control.Button
        Image               matlab.ui.control.Image
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global imagen1
            [filename pathname] = uigetfile({'*.jpg'},'File Selector');
            fullpathname = strcat(pathname,filename);
            imagen1 = imread(fullpathname);
            imshow(imagen1,'Parent',app.UIAxes);
        end

        % Button pushed function: BlancoynegroButton
        function BlancoynegroButtonPushed(app, event)
            global imagen1
            imagen1 = rgb2gray(imagen1);
            imshow(imagen1,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'doctor.png';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'rayos-x.png';
            app.Button.Text = '';
            app.BlancoynegroButton = uibutton(app.UIFigure, 'push');
            app.BlancoynegroButton.ButtonPushedFcn = createCallbackFcn(app, @BlancoynegroButtonPushed, true);
            app.BlancoynegroButton.Text = 'Blanco y negro';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end