classdef table_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ArchivoMenu          matlab.ui.container.Menu
        GuardarMenu          matlab.ui.container.Menu
        ExportarMenu         matlab.ui.container.Menu
        HojadeclculoxlsMenu  matlab.ui.container.Menu
        BlocdenotastxtMenu   matlab.ui.container.Menu
        OpcionesMenu         matlab.ui.container.Menu
        AyudaMenu            matlab.ui.container.Menu
        CapturarButton       matlab.ui.control.Button
        tabla_2              matlab.ui.control.Table
        tabla                matlab.ui.control.Table
        ContextMenu          matlab.ui.container.ContextMenu
        SalirMenu            matlab.ui.container.Menu
        ContextMenu2         matlab.ui.container.ContextMenu
        ReiniciarMenu        matlab.ui.container.Menu
        LimpiarMenu          matlab.ui.container.Menu
        ContextMenu3         matlab.ui.container.ContextMenu
        CambiodecolorMenu    matlab.ui.container.Menu
        AzulMenu             matlab.ui.container.Menu
        VerdeMenu            matlab.ui.container.Menu
        ContextMenu4         matlab.ui.container.ContextMenu
        LimpiarMenu_2        matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global t
            t = readtable('patients.xls');
            a = {'LastName','Gender','Age','Smoker'};
            t = t(1:10,a);% quierolos primeros 10
            categoria = categorical(t.Gender,{'Male','Female','Private'});
            t.Gender = categoria;
            app.tabla.BackgroundColor = [1 0 0;0 1 0;0 1 1];
          
            app.tabla.Data = t;
        end

        % Button pushed function: CapturarButton
        function CapturarButtonPushed(app, event)
            datos = app.tabla.Data;
            app.tabla_2.Data = datos;
        end

        % Menu selected function: SalirMenu
        function SalirMenuSelected(app, event)
            close(app.UIFigure);
        end

        % Menu selected function: ReiniciarMenu
        function ReiniciarMenuSelected(app, event)
            global t
            app.tabla.Data = t;
        end

        % Menu selected function: LimpiarMenu
        function LimpiarMenuSelected(app, event)
            app.tabla.Data = zeros;
        end

        % Menu selected function: AzulMenu
        function AzulMenuSelected(app, event)
            app.CapturarButton.BackgroundColor = [204 255 255]/255;
        end

        % Menu selected function: VerdeMenu
        function VerdeMenuSelected(app, event)
            app.CapturarButton.BackgroundColor = [0 1 0];
        end

        % Context menu opening function: ContextMenu4
        function ContextMenu4Opening(app, event)
            app.tabla_2.Data = zeros;
        end

        % Menu selected function: GuardarMenu
        function GuardarMenuSelected(app, event)
            datos = app.tabla_2.Data;
            assignin("base","datos",datos);
        end

        % Menu selected function: HojadeclculoxlsMenu
        function HojadeclculoxlsMenuSelected(app, event)
            writetable(app.tabla_2.Data,'datos.xls','Sheet','datos');
        end

        % Menu selected function: BlocdenotastxtMenu
        function BlocdenotastxtMenuSelected(app, event)
            writetable(app.tabla_2.Data,'datos.txt','Delimiter',' ');
        end

        % Menu selected function: AyudaMenu
        function AyudaMenuSelected(app, event)
            open("ayuda.pdf");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarMenuSelected, true);
            app.GuardarMenu.Text = 'Guardar';
            app.ExportarMenu = uimenu(app.ArchivoMenu);
            app.ExportarMenu.Text = 'Exportar';
            app.HojadeclculoxlsMenu = uimenu(app.ExportarMenu);
            app.HojadeclculoxlsMenu.MenuSelectedFcn = createCallbackFcn(app, @HojadeclculoxlsMenuSelected, true);
            app.HojadeclculoxlsMenu.Text = 'Hoja de cálculo .xls';
            app.BlocdenotastxtMenu = uimenu(app.ExportarMenu);
            app.BlocdenotastxtMenu.MenuSelectedFcn = createCallbackFcn(app, @BlocdenotastxtMenuSelected, true);
            app.BlocdenotastxtMenu.Text = 'Bloc de notas .txt';
            app.OpcionesMenu = uimenu(app.UIFigure);
            app.OpcionesMenu.Text = 'Opciones';
            app.AyudaMenu = uimenu(app.OpcionesMenu);
            app.AyudaMenu.MenuSelectedFcn = createCallbackFcn(app, @AyudaMenuSelected, true);
            app.AyudaMenu.Text = 'Ayuda';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'Nombre'; 'Género'; 'Edad'; 'Fuma'};
            app.tabla.RowName = {};
            app.tabla.ColumnEditable = true;
            app.tabla_2 = uitable(app.UIFigure);
            app.tabla_2.ColumnName = {'Nombre'; 'Género'; 'Edad'; 'Fuma'};
            app.tabla_2.RowName = {};
            app.tabla_2.ColumnEditable = true;
            app.CapturarButton = uibutton(app.UIFigure, 'push');
            app.CapturarButton.ButtonPushedFcn = createCallbackFcn(app, @CapturarButtonPushed, true);
            app.CapturarButton.Text = 'Capturar';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.SalirMenu = uimenu(app.ContextMenu);
            app.SalirMenu.MenuSelectedFcn = createCallbackFcn(app, @SalirMenuSelected, true);
            app.SalirMenu.Text = 'Salir';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ReiniciarMenu = uimenu(app.ContextMenu2);
            app.ReiniciarMenu.MenuSelectedFcn = createCallbackFcn(app, @ReiniciarMenuSelected, true);
            app.ReiniciarMenu.Text = 'Reiniciar';
            app.LimpiarMenu = uimenu(app.ContextMenu2);
            app.LimpiarMenu.MenuSelectedFcn = createCallbackFcn(app, @LimpiarMenuSelected, true);
            app.LimpiarMenu.Text = 'Limpiar';
            % Assign app.ContextMenu2
            app.tabla.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.CambiodecolorMenu = uimenu(app.ContextMenu3);
            app.CambiodecolorMenu.Text = 'Cambio de color';
            app.AzulMenu = uimenu(app.CambiodecolorMenu);
            app.AzulMenu.MenuSelectedFcn = createCallbackFcn(app, @AzulMenuSelected, true);
            app.AzulMenu.Text = 'Azul';
            app.VerdeMenu = uimenu(app.CambiodecolorMenu);
            app.VerdeMenu.MenuSelectedFcn = createCallbackFcn(app, @VerdeMenuSelected, true);
            app.VerdeMenu.Text = 'Verde';
            % Assign app.ContextMenu3
            app.CapturarButton.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.UIFigure);
            app.ContextMenu4.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu4Opening, true);
            app.LimpiarMenu_2 = uimenu(app.ContextMenu4);
            app.LimpiarMenu_2.Text = 'Limpiar';
            % Assign app.ContextMenu4
            app.tabla_2.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = table_example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end